classdef FLORIDyn_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        FLORIDynAppLabel                matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        PlotPreviewButton               matlab.ui.control.Button
        EnvironmentPanel                matlab.ui.container.Panel
        WindspeedmsEditFieldLabel       matlab.ui.control.Label
        WindspeedmsEditField            matlab.ui.control.NumericEditField
        WinddirdegEditField_2Label      matlab.ui.control.Label
        WinddirdegEditField_2           matlab.ui.control.NumericEditField
        AmbTurbulenceLabel              matlab.ui.control.Label
        AmbTurbulenceEditField          matlab.ui.control.NumericEditField
        ShearcoefficientEditFieldLabel  matlab.ui.control.Label
        ShearcoefficientEditField       matlab.ui.control.NumericEditField
        TurbineSettingsPanel            matlab.ui.container.Panel
        LayoutDropDownLabel             matlab.ui.control.Label
        LayoutDropDown                  matlab.ui.control.DropDown
        YawturbinesseparatedbycommasdegEditFieldLabel  matlab.ui.control.Label
        YawturbinesseparatedbycommasdegEditField  matlab.ui.control.EditField
        NotereadfromthefrontadditionalvaluesareignoredLabel  matlab.ui.control.Label
        OthersettingsPanel              matlab.ui.container.Panel
        ReducedinteractionCheckBox      matlab.ui.control.CheckBox
        PlotflowfieldCheckBox           matlab.ui.control.CheckBox
        StoredataCheckBox               matlab.ui.control.CheckBox
        FoldernameEditFieldLabel        matlab.ui.control.Label
        FoldernameEditField             matlab.ui.control.EditField
        ColorbarrangemsfromEditFieldLabel  matlab.ui.control.Label
        ColorbarrangemsfromEditField    matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        SimulationdurationsEditFieldLabel  matlab.ui.control.Label
        SimulationdurationsEditField    matlab.ui.control.NumericEditField
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        StreamSliceCheckBox             matlab.ui.control.CheckBox
        FromxEditFieldLabel             matlab.ui.control.Label
        FromxEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel_2               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        toxEditFieldLabel               matlab.ui.control.Label
        toxEditField                    matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunningLamp.Color = [1,0,0];
            pause(0.2);
            main_addPaths;
            % Copy data and run simulation
            Control.Type = 'AxialInduction';
            Control.init = true;
            
            [T,fieldLims,Pow,VCpCt,chain] =...
                loadLayout(app.LayoutDropDown.Value);
            
            % Set yaw angles
            Control.yaw  = T.yaw;
            % comma separated yaw angles
            txt = app.YawturbinesseparatedbycommasdegEditField.Value;
            yawTxt = split(erase(txt," "),',');
            for i = 1:min(length(T.yaw),length(yawTxt))
                Control.yaw(i) = str2double(yawTxt{i});
            end
            
            [U, I, UF, Sim] = loadWindField('const',...
                'windAngle',app.WinddirdegEditField_2.Value,...
                'SimDuration',app.SimulationdurationsEditField.Value,...
                'redInteraction',app.ReducedinteractionCheckBox.Value,...
                'alpha_z',app.ShearcoefficientEditField.Value,...
                'windSpeed',app.WindspeedmsEditField.Value,...
                'ambTurbulence',app.AmbTurbulenceEditField.Value);
            
            Vis.online      = false;
            Vis.Snapshots   = false;
            Vis.FlowField   = app.PlotflowfieldCheckBox.Value;
            Vis.PowerOutput = true;
            Vis.Console     = true;
            Vis.CRange      = [app.ColorbarrangemsfromEditField.Value,...
                                app.toEditField.Value];
            Vis.Colormap    = convertCharsToStrings(app.ColormapDropDown.Value);
            Vis.Store       = app.StoredataCheckBox.Value;
            
            if app.StreamSliceCheckBox.Value
                Vis.StreamSlice.end = [app.toxEditField.Value app.yEditField_2.Value];
                Vis.StreamSlice.start = [app.FromxEditField.Value app.yEditField.Value];
                Vis.StreamSlize.Height = app.HeightEditField.Value;
            end
            
            [OP, chain] = assembleOPList(chain,T,'sunflower');
            
            [powerHist,OP,T,chain]=...
                FLORIDyn(T,OP,U,I,UF,Sim,fieldLims,Pow,VCpCt,chain,Vis,...
                Control); %#ok<ASGLU> 
            
            % Save if desired
            if app.StoredataCheckBox.Value
                path = app.FoldernameEditField.Value;
                num = 0;
                while 7==exist(path,"dir")
                    num = num+1;
                    if num > 1
                        path = [path pad(num2str(num),4,'left','0')];
                    else
                        path = [path(1:end-4) pad(num2str(num),4,'left','0')];
                    end
                end
                mkdir(path);
                writematrix(powerHist,[path '\powerHist.csv']);
                movefile('FlowFieldAtHH.vtk',[path '\FlowFieldAtHH.vtk']);
                movefile('FlowFieldAtHH.fig',[path '\FlowFieldAtHH.fig']);
                
                if app.StreamSliceCheckBox.Value
                    movefile('FlowFieldStreamwise.vtk',[path '\FlowFieldStreamwise.vtk']);
                    movefile('FlowFieldStreamwise.fig',[path '\FlowFieldStreamwise.fig']);
                end
            end
            
            
            % Plot power hist
            nT = length(T.D);
            labels = cell(nT,1);
            
            % ========== FLORIDyn data =========
            for iT = 1:length(T.D)
                plot(app.UIAxes3,powerHist(:,1),powerHist(:,iT+1),'LineWidth',1.5)
                if iT == 1; hold(app.UIAxes3,'on');end
                labels{end-nT+iT} = ['T' num2str(iT-1) ' FLORIDyn'];
            end
            
            grid(app.UIAxes3,"on");
            xlim(app.UIAxes3,[0 powerHist(end,1)])
            ylim(app.UIAxes3,[0 inf])
            %xlabel('Time [s]')
            %ylabel('Power generated [W]')
            %title([num2str(nT) ' turbine case'])
            legend(app.UIAxes3,labels)
            hold(app.UIAxes3,'off')
            
            % Simulation clear
            app.RunningLamp.Color = [0,1,0];
        end

        % Button pushed function: PlotPreviewButton
        function PlotPreviewButtonPushed(app, event)
            main_addPaths;
            % Layout preview
            [T,fieldLims,~,~,~] = loadLayout(app.LayoutDropDown.Value);
            windDir = app.WinddirdegEditField_2.Value/180*pi;
            maxL = max(fieldLims(2,:)-fieldLims(1,:));
            WDirP = [...
                fieldLims(1,1)+maxL*0.1,...
                fieldLims(1,2)+maxL*0.1];  
            
            % Wind direction arrow starting point
            quiver(app.UIAxes,WDirP(1),WDirP(2),...
                cos(windDir)*maxL*0.1,...
                sin(windDir)*maxL*0.1,...
                "LineWidth",2,"Color",[1,0,0])
            hold(app.UIAxes,'on')
            
            % Plot un-yawed rotor planes
            for t = 1:length(T.D)
                rot_pos = ...
                    [cos(windDir), -sin(windDir);...
                    sin(windDir), cos(windDir)] * ...
                    [0,0;T.D(t)/2,-T.D(t)/2];
                rot_pos = rot_pos + T.pos(t,1:2)';
                plot(app.UIAxes,rot_pos(1,:),rot_pos(2,:),'b--','LineWidth',.5);
                text(app.UIAxes,T.pos(t,1),T.pos(t,2),...
                    ['\leftarrow T' num2str(t)]);
            end
            
            % Plot yawed rotor planes on top
            
            % Read yaw angles
            yaw  = zeros(size(T.D));
            % comma separated yaw angles
            txt = app.YawturbinesseparatedbycommasdegEditField.Value;
            yawTxt = split(erase(txt," "),',');
            for i = 1:min(length(T.yaw),length(yawTxt))
                yaw(i) = str2double(yawTxt{i})/180*pi;
            end
            
            for t = 1:length(T.D)
                rot_pos = ...
                    [cos(windDir-yaw(t)), -sin(windDir-yaw(t));...
                    sin(windDir-yaw(t)), cos(windDir-yaw(t))] * ...
                    [0,0;T.D(t)/2,-T.D(t)/2];
                rot_pos = rot_pos + T.pos(t,1:2)';
                plot(app.UIAxes,rot_pos(1,:),rot_pos(2,:),'k','LineWidth',1.5);
                text(app.UIAxes,T.pos(t,1),T.pos(t,2),...
                    ['\leftarrow T' num2str(t)]);
            end
            
            % Plot stream slice 
            if app.StreamSliceCheckBox.Value
                sliceX = [app.FromxEditField.Value app.toxEditField.Value];
                sliceY = [app.yEditField.Value app.yEditField_2.Value];
                plot(app.UIAxes,sliceX,sliceY,'g--','LineWidth',1.5);
            end
            
            % Axis properties & grid
            axis(app.UIAxes,'equal')
            grid(app.UIAxes,"on")
            xlim(app.UIAxes,[fieldLims(1,1),fieldLims(2,1)])
            ylim(app.UIAxes,[fieldLims(1,2),fieldLims(2,2)])
            hold(app.UIAxes,'off')
            
            % Windspeed preview
            z = linspace(0,1.5*T.pos(1,3));
            a_s = app.ShearcoefficientEditField.Value;
            u = app.WindspeedmsEditField.Value;
            
            plot(app.UIAxes2,[0,u,u],[T.pos(1,3), T.pos(1,3), 0],'--r')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,((z/T.pos(1,3)).^a_s)*u,z,'LineWidth',1.5);
            grid(app.UIAxes2,"on");
            hold(app.UIAxes2,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FLORIDynAppLabel = uilabel(app.UIFigure);
            app.FLORIDynAppLabel.Text = 'FLORIDyn App';
            app.RunningLamp = uilamp(app.UIFigure);
            app.PlotPreviewButton = uibutton(app.UIFigure, 'push');
            app.PlotPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPreviewButtonPushed, true);
            app.PlotPreviewButton.Text = 'Plot Preview';
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentPanel.Title = 'Environment';
            app.WindspeedmsEditFieldLabel = uilabel(app.EnvironmentPanel);
            app.WindspeedmsEditFieldLabel.Text = 'Wind speed [m/s]';
            app.WindspeedmsEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.WindspeedmsEditField.Limits = [1 30];
            app.WindspeedmsEditField.Value = 8;
            app.WinddirdegEditField_2Label = uilabel(app.EnvironmentPanel);
            app.WinddirdegEditField_2Label.Text = 'Wind dir [deg]';
            app.WinddirdegEditField_2 = uieditfield(app.EnvironmentPanel, 'numeric');
            app.WinddirdegEditField_2.Limits = [-361 361];
            app.WinddirdegEditField_2.Value = 20;
            app.AmbTurbulenceLabel = uilabel(app.EnvironmentPanel);
            app.AmbTurbulenceLabel.Text = 'Amb. Turbulence [%]';
            app.AmbTurbulenceEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.AmbTurbulenceEditField.Limits = [0 0.5];
            app.AmbTurbulenceEditField.Value = 0.05;
            app.ShearcoefficientEditFieldLabel = uilabel(app.EnvironmentPanel);
            app.ShearcoefficientEditFieldLabel.Text = 'Shear coefficient';
            app.ShearcoefficientEditField = uieditfield(app.EnvironmentPanel, 'numeric');
            app.ShearcoefficientEditField.Limits = [0 0.5];
            app.ShearcoefficientEditField.Value = 0.05;
            app.TurbineSettingsPanel = uipanel(app.UIFigure);
            app.TurbineSettingsPanel.Title = 'Turbine Settings';
            app.LayoutDropDownLabel = uilabel(app.TurbineSettingsPanel);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.TurbineSettingsPanel);
            app.LayoutDropDown.Items = {'oneDTU10MW', 'twoDTU10MW', 'threeDTU10MW', 'fourDTU10MW', 'nineDTU10MW', 'FC_oneINNWIND10MW', 'FC_threeINNWIND10MW', 'FC_nineINNWIND10MW'};
            app.LayoutDropDown.Value = 'oneDTU10MW';
            app.YawturbinesseparatedbycommasdegEditFieldLabel = uilabel(app.TurbineSettingsPanel);
            app.YawturbinesseparatedbycommasdegEditFieldLabel.Text = 'Yaw turbine(s), separated by commas [deg]';
            app.YawturbinesseparatedbycommasdegEditField = uieditfield(app.TurbineSettingsPanel, 'text');
            app.YawturbinesseparatedbycommasdegEditField.Value = '0,0,0,0,0,0,0,0,0';
            app.NotereadfromthefrontadditionalvaluesareignoredLabel = uilabel(app.TurbineSettingsPanel);
            app.NotereadfromthefrontadditionalvaluesareignoredLabel.Text = {'Note: read from the front, '; 'additional values are ignored'};
            app.OthersettingsPanel = uipanel(app.UIFigure);
            app.OthersettingsPanel.Title = 'Other settings';
            app.ReducedinteractionCheckBox = uicheckbox(app.OthersettingsPanel);
            app.ReducedinteractionCheckBox.Text = 'Reduced interaction';
            app.ReducedinteractionCheckBox.Value = true;
            app.PlotflowfieldCheckBox = uicheckbox(app.OthersettingsPanel);
            app.PlotflowfieldCheckBox.Text = 'Plot flow field';
            app.PlotflowfieldCheckBox.Value = true;
            app.StoredataCheckBox = uicheckbox(app.OthersettingsPanel);
            app.StoredataCheckBox.Text = 'Store data';
            app.FoldernameEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.FoldernameEditFieldLabel.Text = 'Folder name';
            app.FoldernameEditField = uieditfield(app.OthersettingsPanel, 'text');
            app.ColorbarrangemsfromEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.ColorbarrangemsfromEditFieldLabel.Text = 'Colorbar range [m/s] from';
            app.ColorbarrangemsfromEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.ColorbarrangemsfromEditField.Limits = [0 30];
            app.ColorbarrangemsfromEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.toEditField.Limits = [0 30];
            app.toEditField.Value = 9;
            app.SimulationdurationsEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.SimulationdurationsEditFieldLabel.Text = 'Simulation duration [s]';
            app.SimulationdurationsEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.SimulationdurationsEditField.Limits = [0 1000000];
            app.SimulationdurationsEditField.Value = 1000;
            app.ColormapDropDownLabel = uilabel(app.OthersettingsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.OthersettingsPanel);
            app.ColormapDropDown.Items = {'viridis', 'jet', 'parula', 'hsv'};
            app.ColormapDropDown.Value = 'viridis';
            app.StreamSliceCheckBox = uicheckbox(app.OthersettingsPanel);
            app.StreamSliceCheckBox.Text = 'Stream Slice';
            app.FromxEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.FromxEditFieldLabel.Text = 'From [x]';
            app.FromxEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.yEditFieldLabel.Text = ' [y]';
            app.yEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.yEditFieldLabel_2 = uilabel(app.OthersettingsPanel);
            app.yEditFieldLabel_2.Text = ' [y]';
            app.yEditField_2 = uieditfield(app.OthersettingsPanel, 'numeric');
            app.yEditField_2.Value = 1000;
            app.toxEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.toxEditFieldLabel.Text = 'to [x]';
            app.toxEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.toxEditField.Value = 2000;
            app.HeightEditFieldLabel = uilabel(app.OthersettingsPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.OthersettingsPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.Value = 1000;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wind farm preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Wind shear')
            xlabel(app.UIAxes2, 'Wind speed [m/s]')
            ylabel(app.UIAxes2, 'Height [m]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Generated power of the turbines')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'Generated power [W]')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FLORIDyn_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end